classdef FEQU_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadFileButton             matlab.ui.control.Button
        FuzzyEqualizerPanel        matlab.ui.container.Panel
        Slider2_4                  matlab.ui.control.Slider
        Label_11                   matlab.ui.control.Label
        Slider2_3                  matlab.ui.control.Slider
        Label_10                   matlab.ui.control.Label
        Slider2_2                  matlab.ui.control.Slider
        Label_9                    matlab.ui.control.Label
        Slider2_1                  matlab.ui.control.Slider
        Label_8                    matlab.ui.control.Label
        GraphiicEqualizerPanel     matlab.ui.container.Panel
        Slider1_8                  matlab.ui.control.Slider
        Label_12                   matlab.ui.control.Label
        FLATButton                 matlab.ui.control.StateButton
        Slider1_7                  matlab.ui.control.Slider
        Label_7                    matlab.ui.control.Label
        Slider1_6                  matlab.ui.control.Slider
        Label_6                    matlab.ui.control.Label
        Slider1_5                  matlab.ui.control.Slider
        Label_5                    matlab.ui.control.Label
        Slider1_4                  matlab.ui.control.Slider
        Label_4                    matlab.ui.control.Label
        Slider1_3                  matlab.ui.control.Slider
        Label_3                    matlab.ui.control.Label
        Slider1_2                  matlab.ui.control.Slider
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Slider1_1                  matlab.ui.control.Slider
        ControlPanel               matlab.ui.container.Panel
        SelectBandDropDown         matlab.ui.control.DropDown
        SelectBandDropDownLabel    matlab.ui.control.Label
        GraphButton                matlab.ui.control.StateButton
        TrackTextArea              matlab.ui.control.TextArea
        TrackTextAreaLabel         matlab.ui.control.Label
        OutputDeviceDropDown       matlab.ui.control.DropDown
        OutputDeviceDropDownLabel  matlab.ui.control.Label
        RSTDevButton               matlab.ui.control.Button
        BYPASSButton               matlab.ui.control.StateButton
        PLAYButton                 matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        fs = 44100; % sampling frequency

        % parametric equalizer
        mPEQ % multibandParametricEQ object
        Bands = 7;
        CenterFreqs = [60, 150, 400, 1000, 2400, 6000, 15000];
        MaxdB = 24;  % [-24, +24] dB
        Gains, MasterGain;

        FrameSize = 256; % processing frame size
        BufferSize = 1024; % PC buffer size
        inputFilename
        Output_Device
        PLAY = false;
        BYPASS = false;
        setFile = false;  % File is set?
        RSTDev = false;

        % fuzzy
        % production rule
        memberShip, outMmb;
        Beauty_pr, Power_pr, Depth_pr, productionRule;
        perm, prN;
        fBeauty = 1;  fPower = 2;  fDepth = 3;
        factor = 0;  % factor in operation
        FRange = 12;  % FEQU range (dB)

        % reasoning graph
        fig, ax, pobj, p2obj, p3obj  % graphic objects
        F_out, valuableRule, valRuleN, minFuzziness;

    end
    
    methods (Access = private)
        
        function setFuzzyProp(app)
            % membership function for S/M/L
            % xN = 21;
            fmN = 3;
            S = 1;  M = 2;  L = 3;
            app.memberShip(:,S) = [ones(1,4) 1:-0.25:0 zeros(1,12)]';  % S
            app.memberShip(:,M) = [zeros(1,4) 0:0.25:1 ones(1,3) 1:-0.25:0 zeros(1,4)]';  % M
            app.memberShip(:,L) = [zeros(1,12) 0:0.25:1 ones(1,4)]';  % L

            % output membership function (prototype)
            app.outMmb = [zeros(1,20) 1 zeros(1,20)]';

            % production rule
            % input:antecedent part, output:consequent part
            app.Beauty_pr = zeros(fmN,fmN,fmN);
            app.Power_pr = zeros(fmN,fmN,fmN);
            app.Depth_pr = zeros(fmN,fmN,fmN);
            app.productionRule = zeros(fmN,fmN,fmN,fmN);
            % ex. now=S and man=S then 7, now=S and man=L then 3
            % man  S M L  S M  L   S M  L
            %     [7 0 3; 2 0 -2; -3 0 -7]
            % now  S S S  M M  M   L L  L
            app.Beauty_pr(S,:,:) = [7 0 3; 2 0 -2; -3 0 -7];  % for freq is S
            app.Beauty_pr(M,:,:) = [4 0 6; -1 0 1; -6 0 4];
            app.Beauty_pr(L,:,:) = [10 0 0; 5 0 -5; 0 0 -10];
            app.Power_pr(S,:,:) = [2 0 8; -3 0 3; -8 0 -2];
            app.Power_pr(M,:,:) = [0 0 10; -5 0 5; -10 0 0];
            app.Power_pr(L,:,:) = [2 0 8; -3 0 3; -8 0 -2];
            app.Depth_pr(S,:,:) = [2 0 8; -3 0 3; -8 0 -2];
            app.Depth_pr(M,:,:) = [5 0 5; 0 0 0; -5 0 -5];
            app.Depth_pr(L,:,:) = [10 0 0; 5 0 -5; 0 0 -10];
            app.productionRule(1,:,:,:) = app.Beauty_pr;
            app.productionRule(2,:,:,:) = app.Power_pr;
            app.productionRule(3,:,:,:) = app.Depth_pr;

            % number of production rules for each fuzzy input
            app.prN = numel(app.Beauty_pr);

            % permutation of 1:3
            app.perm = dec2base(0:app.prN-1, 3) - '0' + 1;
        end
        
        function crisp_out = fuzzyReasoning(app,value,type)
            % fuzzy reasoning
            crisp_out = zeros(1,app.mPEQ.NumEQBands);

            % input values
            manIn = value;  % for graph
            manVal = value + 11;  % manipulated value
            for band = 1:app.mPEQ.NumEQBands
                fIn = app.mPEQ.Frequencies(band);  % each center frequency
                fVal = ((log10(fIn) - log10(20)) / (log10(20e3)-log10(20)) * 20) + 1;
                nowVal = app.Gains(band) / app.MaxdB * 10 + 11;  % each gain value
                nowIn = nowVal - 11;  % for graph

                app.F_out = zeros(size(app.memberShip,1),app.prN);
                app.minFuzziness = zeros(1,app.prN);
                for p_rule = 1:length(app.perm)
                    f = app.perm(p_rule,1);
                    now = app.perm(p_rule,2);
                    man = app.perm(p_rule,3);
                    fuzziness = [interp1(1:21,app.memberShip(:,f),fVal), ...
                        interp1(1:21,app.memberShip(:,now),nowVal), ...
                        interp1(1:21,app.memberShip(:,man),manVal)];
                    app.minFuzziness(1,p_rule) = min(fuzziness);

                    out = app.productionRule(type,f,now,man);
                    st = 11 - out;
                    ed = st + 20;
                    app.F_out(:,p_rule) = app.outMmb(st:ed) * min(fuzziness);
                end

                fuzzy_out = max(app.F_out,[],2); % take max
                crisp_out(1,band) = sum(fuzzy_out .* (1:length(fuzzy_out))') ...
                    / sum(fuzzy_out) - 11;  % centroid

                % for graphics
                app.valuableRule = sum(app.F_out,1) > 0;  % index for valuable rules
                app.valRuleN = sum(app.valuableRule);  % number of valuable rules

                if app.GraphButton.Value
                    if band == app.SelectBandDropDown.Value
                        drawReasoningGraph(app,band,nowIn,manIn)
                    end
                end
            end
            setPEQ(app, crisp_out)
        end
        
        function setPEQ(app, crisp_out)
            for band = 1:app.mPEQ.NumEQBands
                gain = app.Gains(band) + crisp_out(1,band)/10 * app.FRange*2;
                gain = max(gain, -app.MaxdB);
                gain = min(gain, +app.MaxdB);
                app.mPEQ.PeakGains(band) = gain;
                switch band
                    case 1
                        app.Slider1_1.Value = gain;
                    case 2
                        app.Slider1_2.Value = gain;
                    case 3
                        app.Slider1_3.Value = gain;
                    case 4
                        app.Slider1_4.Value = gain;
                    case 5
                        app.Slider1_5.Value = gain;
                    case 6
                        app.Slider1_6.Value = gain;
                    case 7
                        app.Slider1_7.Value = gain;
                end
            end
        end
        
        function resetFEQU(app)
            app.factor = 0;
            app.Gains = app.mPEQ.PeakGains; % update gains for FEQU
            app.Slider2_1.Value = 0;
            app.Slider2_2.Value = 0;
            app.Slider2_3.Value = 0;
        end
        
        function [xFig,yFig] = axesPosition2figurePosition(~,data,handle_axes)
            x = data(1);
            y = data(2);
            handle_axes.Units = 'normalize';
            axesPos = handle_axes.Position;
            widthData = handle_axes.XLim(2)-handle_axes.XLim(1);
            heightData = handle_axes.YLim(2)-handle_axes.YLim(1);
            xmin = handle_axes.XLim(1);
            ymin = handle_axes.YLim(1);

            xFig = (x-xmin)/widthData*axesPos(3) + axesPos(1);
            yFig = (y-ymin)/heightData*axesPos(4) + axesPos(2);
        end
        
        function drawReasoningGraph(app,band,nowIn,manIn)
            % グラフが非表示または破棄されている場合は描画処理をスキップ
            if isempty(app.fig) || ~isvalid(app.fig) || strcmp(app.fig.Visible, 'off')
                return;
            end

            f = app.CenterFreqs(band);
            fVal = ((log10(f) - log10(20)) / (log10(20e3)-log10(20)) * 20) + 1;

            % --- プロットデータ(YData)のダイレクト更新 ---
            r = 1;
            for n=find(app.valuableRule)
                for c=1:3
                    app.pobj(r,c).YData = app.memberShip(:,app.perm(n,c));
                end
                app.pobj(r,4).YData = app.F_out(:,n);
                r = r + 1;
            end
            n_zero = find(app.valuableRule==0);
            for r=1:8-app.valRuleN
                for c=1:3
                    app.pobj(app.valRuleN+r,c).YData = app.memberShip(:,app.perm(n_zero(r),c));
                end
                app.pobj(app.valRuleN+r,4).YData = app.F_out(:,n_zero(r));
            end

            % --- Fitness lines(赤線)の位置更新 ---
            r = 1;
            for n=find(app.valuableRule)
                [~,y2] = axesPosition2figurePosition(app,[10,app.minFuzziness(n)],app.ax(r,4));
                app.p2obj(1,r).Y = [y2 y2];
                r = r + 1;
            end
            for r=1:8-app.valRuleN
                [~,y2] = axesPosition2figurePosition(app,[10,0],app.ax(app.valRuleN+r,4));
                app.p2obj(1,app.valRuleN+r).Y = [y2 y2];
            end

            % --- Crisp Output (重心計算) と線の更新 ---
            fuzzy_out = max(app.F_out,[],2); % take max
            crisp_out = sum(fuzzy_out .* (1:length(fuzzy_out))') / sum(fuzzy_out) - 11; % centroid

            app.pobj(end,end).YData = fuzzy_out;
            % スカラー判定のフェールセーフ
            if isscalar(crisp_out) && isfinite(crisp_out)
                app.p3obj(1,4).Value = crisp_out;
            else
                app.p3obj(1,4).Value = 0;
            end

            % --- Input values (破線アロー) の位置更新 ---
            [x1,~] = axesPosition2figurePosition(app,[fVal,1],app.ax(1,1));
            app.p3obj(1,1).X = [x1 x1];

            [x1,~] = axesPosition2figurePosition(app,[nowIn,1],app.ax(1,2));
            app.p3obj(1,2).X = [x1 x1];

            [x1,~] = axesPosition2figurePosition(app,[manIn,1],app.ax(1,3));
            app.p3obj(1,3).X = [x1 x1];

            switch app.factor
                case 1, t = 'Beauty Factor';
                case 2, t = 'Power Factor';
                case 3, t = 'Depth Factor';
                otherwise, t = '';
            end
            sgtitle(app.fig, t);

            % シングルスレッドでのイベント詰まりを防止
            drawnow limitrate;
        end
        
        function initFuzzyGraph(app)
            figWidth = 840;  figHight = 900;

            % Figure背景を白に固定。×ボタンで閉じられても破棄せず非表示にするフェールセーフ
            app.fig = figure('Theme', 'light', 'MenuBar', 'none', 'Color', 'w', ...
                'NumberTitle', 'off', 'Name', 'Fuzzy Reasoning', ...
                'Position', [200, 200, figWidth, figHight], ...
                'Visible', 'off', ...
                'CloseRequestFcn', @(src,ev) set(src, 'Visible', 'off'));

            t = tiledlayout(app.fig, 9, 4, 'TileSpacing', 'compact', 'Padding', 'compact');
            app.ax = gobjects(9, 4);
            app.pobj = gobjects(9, 4);
            col = [51 153 102]/255;

            % AxesとPlotの初期構築
            for r=1:8
                for c=1:3
                    a = nexttile(t);
                    app.ax(r,c) = a;
                    set(a, 'Color', 'w', 'XGrid', 'on', 'YGrid', 'on'); % Axes背景白

                    if c==1  % frequency
                        app.pobj(r,c) = plot(a, 1:21, zeros(21,1), 'Color', col, 'LineWidth', 1);
                        set(a, 'XLim', [1 21]);
                        finx = 10 .^ ( (a.XTick-1)/20 * (log10(20e3)-log10(20)) + log10(20));
                        a.XTickLabel = string(round(finx));
                    else
                        app.pobj(r,c) = plot(a, -10:10, zeros(21,1), 'Color', col, 'LineWidth', 1);
                        set(a, 'XLim', [-10 10]);
                    end
                end
                a = nexttile(t);
                app.ax(r,4) = a;
                app.pobj(r,4) = plot(a, -10:10, zeros(21,1), 'Color', [0.6 0.6 0.6], 'LineWidth', 1.2);
                set(a, 'Color', 'w', 'YLim', [0 1], 'XGrid', 'on', 'YGrid', 'on');
            end

            % 最終出力タイル
            a = nexttile(t, 36);
            app.ax(9,4) = a;
            app.pobj(9,4) = plot(a, -10:10, zeros(21,1));
            set(a, 'Color', 'w', 'YLim', [0 1], 'XGrid', 'on', 'YGrid', 'on');

            % レイアウトを確定させてAnnotationの座標計算を可能にする（必須）
            drawnow;

            % Fitness lines (赤線)
            app.p2obj = gobjects(1,8);
            for n=1:8
                [x1,~] = axesPosition2figurePosition(app,[0,1],app.ax(1,1));
                [x2,y2] = axesPosition2figurePosition(app,[10,1],app.ax(n,4));
                app.p2obj(1,n) = annotation(app.fig, 'line', [x1 x2], [y2 y2], 'Color', 'r');
            end

            % 入力値とCrisp Outputの線
            app.p3obj = gobjects(1,4);
            app.p3obj(1,4) = xline(a, 0, 'LineWidth', 1.5, 'Color', [0.6350 0.0780 0.1840]);

            textArgs = {'LineStyle', ':', 'LineWidth', 1.5, 'TextBackgroundColor', 'white', ...
                'FontSize', 14, 'Color', [0 0.4470 0.7410], 'HeadStyle', 'none'};

            [x1,y1] = axesPosition2figurePosition(app,[1,1],app.ax(1,1));
            [~,y2]  = axesPosition2figurePosition(app,[0,0.5],app.ax(9,4));

            app.p3obj(1,1) = annotation(app.fig, 'textarrow', [x1 x1], [y2 y1], 'String', "Frequency", textArgs{:});
            [x1,~] = axesPosition2figurePosition(app,[0,1],app.ax(1,2));
            app.p3obj(1,2) = annotation(app.fig, 'textarrow', [x1 x1], [y2 y1], 'String', "Now", textArgs{:});
            [x1,~] = axesPosition2figurePosition(app,[0,1],app.ax(1,3));
            app.p3obj(1,3) = annotation(app.fig, 'textarrow', [x1 x1], [y2 y1], 'String', "Input", textArgs{:});
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set output devices
            info = audiodevinfo;
            dev_n = length(info.output);
            sa = struct2cell(info.output);
            sa_Name = sa(1,:,:);
            sa_Name_s = squeeze(sa_Name);
            for i=1:dev_n
                idx = strfind(sa_Name_s{i},'(');
                if isempty(idx)
                    idx = length(sa_Name_s{i});
                else
                    idx = idx(end) - 1;
                end
                sa_Name_s{i} = strtrim(sa_Name_s{i}(1:idx));
            end
            app.OutputDeviceDropDown.Items = sa_Name_s;
            app.Output_Device = sa_Name_s{1};
            app.OutputDeviceDropDown.Value = app.Output_Device;

            app.MasterGain = app.Slider1_8.Value;

            if ~app.RSTDev
                app.PLAYButton.BackgroundColor = [0.75 0.75 0.75];

                app.mPEQ = multibandParametricEQ('NumEQBands',app.Bands, ...
                    'EQOrder',4, 'SampleRate',app.fs, ...
                    'Frequencies',app.CenterFreqs, ...
                    'PeakGains',ones(1,7)*0,'QualityFactors',ones(1,7)*1.6);
                app.Gains = app.mPEQ.PeakGains;
                % drawFrequencyResponse(app);
            else
                app.RSTDev = false;
            end

            app.SelectBandDropDown.Items = string(app.CenterFreqs);
            app.SelectBandDropDown.ItemsData = 1:app.Bands;
            app.SelectBandDropDown.Value = ceil(app.Bands / 2) + 1;
            setFuzzyProp(app);
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [FileName, PathName, ~] = uigetfile({['*.mp3;*.m4a;*.aac;*.wav;* ...' ...
                '.mp4;*.flac;*.ogg;*.webm'],'Music Files';'*.*','All Files'}, ...
                'Select Song', 'MultiSelect', 'off');
            if FileName ~= 0
                app.inputFilename = [PathName FileName];
                m_info = audioinfo(app.inputFilename);
                app.fs = m_info.SampleRate;
                app.mPEQ.SampleRate = app.fs;
                app.setFile = true;
                app.PLAYButton.BackgroundColor = [1 1 1];
                app.TrackTextArea.Value = FileName;

                if app.PLAY % if so force stop
                    app.PLAYButton.Value = false;
                    PLAYButtonValueChanged(app, event);
                end
            end
        end

        % Value changed function: OutputDeviceDropDown
        function OutputDeviceDropDownValueChanged(app, event)
            value = app.OutputDeviceDropDown.Value;
            if ~app.PLAY % while STOP state only
                app.Output_Device = value;
            else
                app.OutputDeviceDropDown.Value = app.Output_Device;
            end
        end

        % Button pushed function: RSTDevButton
        function RSTDevButtonPushed(app, event)
            app.RSTDev = true;
            audiodevreset
            startupFcn(app);
        end

        % Value changed function: PLAYButton
        function PLAYButtonValueChanged(app, event)
            value = app.PLAYButton.Value;
            if ~app.setFile % file isn't set
                app.PLAYButton.Value = false;
                return;
            end

            app.PLAY = value;
            if app.PLAY
                app.PLAYButton.Text = 'STOP';
            else
                app.PLAYButton.Text = 'PLAY';
            end

            if app.PLAY
                hAudioSource = dsp.AudioFileReader(app.inputFilename,'SamplesPerFrame',app.FrameSize);
                % hAudioOut = audioDeviceWriter('DeviceName', app.Output_Device, 'SampleRate', hAudioSource.SampleRate,...
                %     'SupportVariableSizeInput', true, 'BufferSize', app.BufferSize);
                if usejava('desktop')
                    hAudioOut = audioDeviceWriter('DeviceName', app.Output_Device, 'SampleRate', hAudioSource.SampleRate, ...
                        'SupportVariableSizeInput', true, 'BufferSize', app.BufferSize);
                else
                    uialert(app.UIFigure, ...
                        ['Real-time audio playback is not supported in MATLAB Online. ' ...
                        'Please run this application in desktop MATLAB.'], ...
                        'Audio Output Unavailable');
                    return;
                end

                while app.PLAY
                    u = step(hAudioSource);
                    if size(u,2) >= 2
                        u_st = u(:,1:2);
                    else  % mono
                        u_st = [u(:,1) u(:,1)];
                    end

                    if isDone(hAudioSource) % Loop Play
                        reset(hAudioSource);
                    end

                    gain = db2mag(app.MasterGain);
                    if app.BYPASS
                        out = u_st * gain;
                    else
                        out = app.mPEQ(u_st) * gain;
                    end
                    hAudioOut(out);  % sound out
                    drawnow limitrate % for GUI interrupt
                    if ~isvalid(app); break; end % check unexpected force termination by user
                end
                reset(hAudioSource);
            end
        end

        % Value changed function: Slider1_1
        function Slider1_1ValueChanged(app, event)
            value = app.Slider1_1.Value;
            app.mPEQ.PeakGains(1) = value;
            resetFEQU(app)
        end

        % Value changed function: Slider1_2
        function Slider1_2ValueChanged(app, event)
            value = app.Slider1_2.Value;
            app.mPEQ.PeakGains(2) = value;
            resetFEQU(app)
        end

        % Value changed function: Slider1_3
        function Slider1_3ValueChanged(app, event)
            value = app.Slider1_3.Value;
            app.mPEQ.PeakGains(3) = value;
            resetFEQU(app)
        end

        % Value changed function: Slider1_4
        function Slider1_4ValueChanged(app, event)
            value = app.Slider1_4.Value;
            app.mPEQ.PeakGains(4) = value;
            resetFEQU(app)
        end

        % Value changed function: Slider1_5
        function Slider1_5ValueChanged(app, event)
            value = app.Slider1_5.Value;
            app.mPEQ.PeakGains(5) = value;
            resetFEQU(app)
        end

        % Value changed function: Slider1_6
        function Slider1_6ValueChanged(app, event)
            value = app.Slider1_6.Value;
            app.mPEQ.PeakGains(6) = value;
            resetFEQU(app)
        end

        % Value changed function: Slider1_7
        function Slider1_7ValueChanged(app, event)
            value = app.Slider1_7.Value;
            app.mPEQ.PeakGains(7) = value;
            resetFEQU(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            release(app.mPEQ)
            if exist('hAudioSource','var'); release(hAudioSource); end
            if exist('hAudioOut','var'); release(hAudioOut); end
            if ishandle(app.fig); close(app.fig); end
            delete(app)
        end

        % Value changed function: BYPASSButton
        function BYPASSButtonValueChanged(app, event)
            value = app.BYPASSButton.Value;
            app.BYPASS = value;
        end

        % Value changed function: FLATButton
        function FLATButtonValueChanged(app, event)
            app.Slider1_1.Value = 0;
            app.mPEQ.PeakGains(1) = 0;
            app.Slider1_2.Value = 0;
            app.mPEQ.PeakGains(2) = 0;
            app.Slider1_3.Value = 0;
            app.mPEQ.PeakGains(3) = 0;
            app.Slider1_4.Value = 0;
            app.mPEQ.PeakGains(4) = 0;
            app.Slider1_5.Value = 0;
            app.mPEQ.PeakGains(5) = 0;
            app.Slider1_6.Value = 0;
            app.mPEQ.PeakGains(6) = 0;
            app.Slider1_7.Value = 0;
            app.mPEQ.PeakGains(7) = 0;
            resetFEQU(app)
        end

        % Value changed function: Slider2_1
        function Slider2_1ValueChanged(app, event)
            value = app.Slider2_1.Value;
            if app.factor ~= 1
                app.Gains = app.mPEQ.PeakGains; % update gains for FEQU
            end
            app.factor = 1;
            app.Slider2_2.Value = 0;
            app.Slider2_3.Value = 0;
            fuzzyReasoning(app,value,app.fBeauty);
        end

        % Value changed function: Slider2_2
        function Slider2_2ValueChanged(app, event)
            value = app.Slider2_2.Value;
            if app.factor ~= 2
                app.Gains = app.mPEQ.PeakGains; % update gains for FEQU
            end
            app.factor = 2;
            app.Slider2_1.Value = 0;
            app.Slider2_3.Value = 0;
            fuzzyReasoning(app,value,app.fPower);
        end

        % Value changed function: Slider2_3
        function Slider2_3ValueChanged(app, event)
            value = app.Slider2_3.Value;
            if app.factor ~= 3
                app.Gains = app.mPEQ.PeakGains; % update gains for FEQU
            end
            app.factor = 3;
            app.Slider2_1.Value = 0;
            app.Slider2_2.Value = 0;
            fuzzyReasoning(app,value,app.fDepth);
        end

        % Value changed function: Slider2_4
        function Slider2_4ValueChanged(app, event)
            value = app.Slider2_4.Value;
            value = round(value/3)*3;
            app.Slider2_4.Value = value;
            app.FRange = value;
        end

        % Value changed function: Slider1_8
        function Slider1_8ValueChanged(app, event)
            value = app.Slider1_8.Value;
            value = round(value);
            app.Slider1_8.Value = value;
            app.MasterGain = value;
        end

        % Value changed function: GraphButton
        function GraphButtonValueChanged(app, event)
            value = app.GraphButton.Value;
            if value
                % Figure未生成または破棄済みの場合は初期構築
                if isempty(app.fig) || ~isvalid(app.fig)
                    initFuzzyGraph(app);
                end

                app.fig.Visible = 'on';
                fuzzyReasoning(app, 0, app.fBeauty);  % initialize & draw
            else
                % ウィンドウを破棄せず非表示にする（次回開くときの負荷ゼロ）
                if ~isempty(app.fig) && isvalid(app.fig)
                    app.fig.Visible = 'off';
                end
            end
        end

        % Value changed function: SelectBandDropDown
        function SelectBandDropDownValueChanged(app, event)
            % value = app.SelectBandDropDown.Value;
            switch app.factor
                case app.fBeauty
                    type = app.fBeauty;
                    val = app.Slider2_1.Value;
                case app.fBeauty
                    type = app.fPower;
                    val = app.Slider2_2.Value;
                case app.fBeauty
                    type = app.fDepth;
                    val = app.Slider2_3.Value;
                otherwise
                    type = app.fBeauty;
                    val = 0;
            end
            fuzzyReasoning(app,val,type);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.PLAYButton = uibutton(app.ControlPanel, 'state');
            app.PLAYButton.ValueChangedFcn = createCallbackFcn(app, @PLAYButtonValueChanged, true);
            app.PLAYButton.Text = 'PLAY';
            app.BYPASSButton = uibutton(app.ControlPanel, 'state');
            app.BYPASSButton.ValueChangedFcn = createCallbackFcn(app, @BYPASSButtonValueChanged, true);
            app.BYPASSButton.Text = 'BYPASS';
            app.RSTDevButton = uibutton(app.ControlPanel, 'push');
            app.RSTDevButton.ButtonPushedFcn = createCallbackFcn(app, @RSTDevButtonPushed, true);
            app.RSTDevButton.Text = 'RSTDev';
            app.OutputDeviceDropDownLabel = uilabel(app.ControlPanel);
            app.OutputDeviceDropDownLabel.Text = 'OutputDevice';
            app.OutputDeviceDropDown = uidropdown(app.ControlPanel);
            app.OutputDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputDeviceDropDownValueChanged, true);
            app.TrackTextAreaLabel = uilabel(app.ControlPanel);
            app.TrackTextAreaLabel.Text = 'Track';
            app.TrackTextArea = uitextarea(app.ControlPanel);
            app.TrackTextArea.Value = {'最初にオーディオファイルを読み込んでください'};
            app.GraphButton = uibutton(app.ControlPanel, 'state');
            app.GraphButton.ValueChangedFcn = createCallbackFcn(app, @GraphButtonValueChanged, true);
            app.GraphButton.Text = 'Graph';
            app.SelectBandDropDownLabel = uilabel(app.ControlPanel);
            app.SelectBandDropDownLabel.Text = 'Select Band';
            app.SelectBandDropDown = uidropdown(app.ControlPanel);
            app.SelectBandDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectBandDropDownValueChanged, true);
            app.GraphiicEqualizerPanel = uipanel(app.UIFigure);
            app.GraphiicEqualizerPanel.Title = 'Graphiic Equalizer';
            app.Slider1_1 = uislider(app.GraphiicEqualizerPanel);
            app.Slider1_1.Limits = [-24 24];
            app.Slider1_1.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.Slider1_1.ValueChangedFcn = createCallbackFcn(app, @Slider1_1ValueChanged, true);
            app.Label = uilabel(app.GraphiicEqualizerPanel);
            app.Label.Text = '60';
            app.Label_2 = uilabel(app.GraphiicEqualizerPanel);
            app.Label_2.Text = '150';
            app.Slider1_2 = uislider(app.GraphiicEqualizerPanel);
            app.Slider1_2.Limits = [-24 24];
            app.Slider1_2.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.Slider1_2.ValueChangedFcn = createCallbackFcn(app, @Slider1_2ValueChanged, true);
            app.Label_3 = uilabel(app.GraphiicEqualizerPanel);
            app.Label_3.Text = '400';
            app.Slider1_3 = uislider(app.GraphiicEqualizerPanel);
            app.Slider1_3.Limits = [-24 24];
            app.Slider1_3.ValueChangedFcn = createCallbackFcn(app, @Slider1_3ValueChanged, true);
            app.Label_4 = uilabel(app.GraphiicEqualizerPanel);
            app.Label_4.Text = '1k';
            app.Slider1_4 = uislider(app.GraphiicEqualizerPanel);
            app.Slider1_4.Limits = [-24 24];
            app.Slider1_4.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.Slider1_4.ValueChangedFcn = createCallbackFcn(app, @Slider1_4ValueChanged, true);
            app.Label_5 = uilabel(app.GraphiicEqualizerPanel);
            app.Label_5.Text = '2.4k';
            app.Slider1_5 = uislider(app.GraphiicEqualizerPanel);
            app.Slider1_5.Limits = [-24 24];
            app.Slider1_5.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.Slider1_5.ValueChangedFcn = createCallbackFcn(app, @Slider1_5ValueChanged, true);
            app.Label_6 = uilabel(app.GraphiicEqualizerPanel);
            app.Label_6.Text = '6k';
            app.Slider1_6 = uislider(app.GraphiicEqualizerPanel);
            app.Slider1_6.Limits = [-24 24];
            app.Slider1_6.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.Slider1_6.ValueChangedFcn = createCallbackFcn(app, @Slider1_6ValueChanged, true);
            app.Label_7 = uilabel(app.GraphiicEqualizerPanel);
            app.Label_7.Text = '15k';
            app.Slider1_7 = uislider(app.GraphiicEqualizerPanel);
            app.Slider1_7.Limits = [-24 24];
            app.Slider1_7.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.Slider1_7.ValueChangedFcn = createCallbackFcn(app, @Slider1_7ValueChanged, true);
            app.FLATButton = uibutton(app.GraphiicEqualizerPanel, 'state');
            app.FLATButton.ValueChangedFcn = createCallbackFcn(app, @FLATButtonValueChanged, true);
            app.FLATButton.Text = 'FLAT';
            app.Label_12 = uilabel(app.GraphiicEqualizerPanel);
            app.Label_12.Text = {'Master'; 'Gain'; ' (dB)'};
            app.Slider1_8 = uislider(app.GraphiicEqualizerPanel);
            app.Slider1_8.Limits = [-12 12];
            app.Slider1_8.MajorTicks = [-24 -18 -12 -6 0 6 12 18 24];
            app.Slider1_8.ValueChangedFcn = createCallbackFcn(app, @Slider1_8ValueChanged, true);
            app.Slider1_8.Value = -6;
            app.FuzzyEqualizerPanel = uipanel(app.UIFigure);
            app.FuzzyEqualizerPanel.Title = 'Fuzzy Equalizer';
            app.Label_8 = uilabel(app.FuzzyEqualizerPanel);
            app.Label_8.Text = 'Beauty';
            app.Slider2_1 = uislider(app.FuzzyEqualizerPanel);
            app.Slider2_1.Limits = [-10 10];
            app.Slider2_1.MajorTicks = [-10 -5 0 5 10];
            app.Slider2_1.ValueChangedFcn = createCallbackFcn(app, @Slider2_1ValueChanged, true);
            app.Label_9 = uilabel(app.FuzzyEqualizerPanel);
            app.Label_9.Text = 'Power';
            app.Slider2_2 = uislider(app.FuzzyEqualizerPanel);
            app.Slider2_2.Limits = [-10 10];
            app.Slider2_2.MajorTicks = [-10 -5 0 5 10];
            app.Slider2_2.ValueChangedFcn = createCallbackFcn(app, @Slider2_2ValueChanged, true);
            app.Label_10 = uilabel(app.FuzzyEqualizerPanel);
            app.Label_10.Text = 'Depth';
            app.Slider2_3 = uislider(app.FuzzyEqualizerPanel);
            app.Slider2_3.Limits = [-10 10];
            app.Slider2_3.MajorTicks = [-10 -5 0 5 10];
            app.Slider2_3.ValueChangedFcn = createCallbackFcn(app, @Slider2_3ValueChanged, true);
            app.Label_11 = uilabel(app.FuzzyEqualizerPanel);
            app.Label_11.Text = 'Range (dB)';
            app.Slider2_4 = uislider(app.FuzzyEqualizerPanel);
            app.Slider2_4.Limits = [6 24];
            app.Slider2_4.MajorTicks = [6 12 18 24];
            app.Slider2_4.ValueChangedFcn = createCallbackFcn(app, @Slider2_4ValueChanged, true);
            app.Slider2_4.MinorTicks = [6 9 12 15 18 21 24];
            app.Slider2_4.Value = 12;
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'LoadFile';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FEQU_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end